classdef toolbox_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField  matlab.ui.control.Label            % Time st...
        deltaT                 matlab.ui.control.NumericEditField % [0 1.00...
        LabelNumericEditField2 matlab.ui.control.Label            % Impact ...
        delta_dx               matlab.ui.control.NumericEditField % [-1 -1....
        Label                  matlab.ui.control.Label            % Desired...
        F_d                    matlab.ui.control.NumericEditField % [0 10]
        Label2                 matlab.ui.control.Label            % Final S...
        Tfinal                 matlab.ui.control.NumericEditField % [0 1000]
        Animation              matlab.ui.control.CheckBox         % Animation
        LabelSlider            matlab.ui.control.Label            % X-axis ...
        Limitsx                matlab.ui.control.Slider           % [0 10]
        Label3                 matlab.ui.control.Label            % Y-axis ...
        Limitsy                matlab.ui.control.Slider           % [0 10]
        On_surface             matlab.ui.control.Button           % Contact...
        Off_surface            matlab.ui.control.Button           % Contact...
        Label4                 matlab.ui.control.Label            % Example...
        LabelSlider2           matlab.ui.control.Label            % Rho
        Rho                    matlab.ui.control.Slider           % [5.0000...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('CoDS'))
            setup_CoDs;
        end

        % On_surface button pushed function
        function On_surfaceButtonPushed(app)
            Option.limits= [-app.Limitsx.Value app.Limitsx.Value -app.Limitsy.Value app.Limitsy.Value];
            Option.check=0;
            Option.rho=app.Rho.Value;
            Option.Deltat=app.deltaT.Value;
            Option.F_d=app.F_d.Value;
            Option.delta_dx=app.delta_dx.Value;
            Option.Tfinal=app.Tfinal.Value;
            Option.animation=app.Animation.Value;
            Option.Onsurface=1;
            disp('Draw the contact surface')
            [N_x,Poly,X_target,X_free,Option.check]=Construct_the_surface_for_on_surface(Option);
            if Option.check==0
                error('Program exit')
            end
            disp('Draw some motions, make sure that it ends up at the target point and it goes through the contact surface !')
            [A,X_initial,Option.check]=Construct_the_dynamcial_system(Poly,X_target,X_free,Option);
            if Option.check==0
                error('Program exit')
            end
            [X_C,X_L]=Select_the_contact_point_on_surface(Poly,X_target,X_initial,Option);
            [DDX_modulated,DX_modulated,X_modulated,F_modulated,Time_modulated]=  simulate_modulated_system(A,N_x,Poly,X_initial,X_target,X_free,X_C,X_L,Option);
            plot_the_simualtions(DDX_modulated,DX_modulated,X_modulated,F_modulated,Time_modulated,Poly,X_initial,X_target,X_free,X_C,X_L,N_x,Option);
        end

        % Off_surface button pushed function
        function Off_surfaceButtonPushed(app)
            Option.limits= [-app.Limitsx.Value app.Limitsx.Value -app.Limitsy.Value app.Limitsy.Value];
            Option.check=0;
            Option.rho=app.Rho.Value;
            Option.Deltat=app.deltaT.Value;
            Option.F_d=app.F_d.Value;
            Option.delta_dx=app.delta_dx.Value;
            Option.Tfinal=app.Tfinal.Value;
            Option.animation=app.Animation.Value;
            Option.Onsurface=0;
            disp('Draw the contact surface')
            [N_x,Poly,X_target,Option.check]=Construct_the_surface(Option);
            if Option.check==0
                error('Program exit')
            end
            %% Constructing the dynamcail system
            disp('Draw some motions, make sure that it ends up at the target point and it goes through the contact surface !')
            [A,X_initial,Option.check]=Construct_the_dynamcial_system(Poly,X_target,X_target,Option);
            if Option.check==0
                error('Program exit')
            end
            [X_C,X_L]=Select_the_contact_point(Poly,X_target,X_initial,Option);
            %% Simulate the unmodulated system
            [DDX_modulated,DX_modulated,X_modulated,F_modulated,Time_modulated]=  simulate_modulated_system(A,N_x,Poly,X_initial,X_target,X_target,X_C,X_L,Option);
            plot_the_simualtions(DDX_modulated,DX_modulated,X_modulated,F_modulated,Time_modulated,Poly,X_initial,X_target,X_target,X_C,X_L,N_x,Option);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Time step [s]';
            app.deltaT = uieditfield(app.UIFigure, 'numeric');
            app.deltaT.Limits = [0 0.001];
            app.deltaT.Value = 0.0001;
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Impact velocity [m/s]';
            app.delta_dx = uieditfield(app.UIFigure, 'numeric');
            app.delta_dx.Limits = [-1 -1e-05];
            app.delta_dx.Value = -0.5;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Desired contact force [N]';
            app.F_d = uieditfield(app.UIFigure, 'numeric');
            app.F_d.Limits = [0 10];
            app.F_d.Value = 6;
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Final Simulation time [s]';
            app.Tfinal = uieditfield(app.UIFigure, 'numeric');
            app.Tfinal.Limits = [0 1000];
            app.Tfinal.Value = 100;
            app.Animation = uicheckbox(app.UIFigure);
            app.Animation.Text = 'Animation';
            app.Animation.Value = true;
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'X-axis Limits';
            app.Limitsx = uislider(app.UIFigure);
            app.Limitsx.Limits = [0 10];
            app.Limitsx.Value = 5;
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Y-axis Limits';
            app.Limitsy = uislider(app.UIFigure);
            app.Limitsy.Limits = [0 10];
            app.Limitsy.Value = 5;
            app.On_surface = uibutton(app.UIFigure, 'push');
            app.On_surface.ButtonPushedFcn = createCallbackFcn(app, @On_surfaceButtonPushed);
            app.On_surface.Text = 'Contact on the surface';
            app.Off_surface = uibutton(app.UIFigure, 'push');
            app.Off_surface.ButtonPushedFcn = createCallbackFcn(app, @Off_surfaceButtonPushed);
            app.Off_surface.Text = 'Contact outside the surface';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = {'Examples of using the toolbox are available'; ' in the folder named "Video" .'};
            app.LabelSlider2 = uilabel(app.UIFigure);
            app.LabelSlider2.Text = 'Rho';
            app.Rho = uislider(app.UIFigure);
            app.Rho.Limits = [0.5 10];
            app.Rho.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = toolbox_GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end